classdef gui_input_parameters < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        InputParametersUIFigure       matlab.ui.Figure
        TabGroup2                     matlab.ui.container.TabGroup
        BodyTab                       matlab.ui.container.Tab
        FrontSuspensionPanel          matlab.ui.container.Panel
        UnsprungMassEditFieldLabel    matlab.ui.control.Label
        UnsprungMassEditField         matlab.ui.control.NumericEditField
        DampingEditFieldLabel         matlab.ui.control.Label
        DampingEditField              matlab.ui.control.NumericEditField
        StiffnessEditFieldLabel       matlab.ui.control.Label
        StiffnessEditField            matlab.ui.control.NumericEditField
        SprungMassPanel               matlab.ui.container.Panel
        ChasisIDEditFieldLabel        matlab.ui.control.Label
        ChasisIDEditField             matlab.ui.control.EditField
        SprungMassEditFieldLabel      matlab.ui.control.Label
        SprungMassEditField           matlab.ui.control.NumericEditField
        DefineCoordinateButton        matlab.ui.control.Button
        I_xxEditFieldLabel            matlab.ui.control.Label
        I_xxEditField                 matlab.ui.control.NumericEditField
        I_yyEditFieldLabel            matlab.ui.control.Label
        I_yyEditField                 matlab.ui.control.NumericEditField
        WheelPositionButton           matlab.ui.control.Button
        RearSuspensionPanel           matlab.ui.container.Panel
        UnsprungMassEditField_2Label  matlab.ui.control.Label
        UnsprungMassEditField_2       matlab.ui.control.NumericEditField
        DampingEditField_2Label       matlab.ui.control.Label
        DampingEditField_2            matlab.ui.control.NumericEditField
        StiffnessEditField_2Label     matlab.ui.control.Label
        StiffnessEditField_2          matlab.ui.control.NumericEditField
        TirePanel                     matlab.ui.container.Panel
        ModelEditFieldLabel           matlab.ui.control.Label
        ModelEditField                matlab.ui.control.EditField
        StiffnessEditField_3Label     matlab.ui.control.Label
        StiffnessEditField_3          matlab.ui.control.NumericEditField
        EngineTab                     matlab.ui.container.Tab
        EngineNameEditFieldLabel      matlab.ui.control.Label
        EngineNameEditField           matlab.ui.control.EditField
        MasskgEditFieldLabel          matlab.ui.control.Label
        MasskgEditField               matlab.ui.control.NumericEditField
        SetinertiaButton              matlab.ui.control.Button
        Inertiamatrixkgm2EditFieldLabel  matlab.ui.control.Label
        Inertiamatrixkgm2EditField    matlab.ui.control.NumericEditField
        IdlespeedEditFieldLabel       matlab.ui.control.Label
        IdlespeedEditField            matlab.ui.control.NumericEditField
        TorqueIdlespeedEditField_2Label  matlab.ui.control.Label
        TorqueIdlespeedEditField_2    matlab.ui.control.NumericEditField
        EngineModelEditFieldLabel     matlab.ui.control.Label
        EngineModelEditField          matlab.ui.control.EditField
        EngineMountsTab               matlab.ui.container.Tab
        ConfigurationDropDownLabel    matlab.ui.control.Label
        ConfigurationDropDown         matlab.ui.control.DropDown
        LocationTab                   matlab.ui.container.Tab
        panel_con_orientation         matlab.ui.container.Panel
        sw_mount1ori                  matlab.ui.control.Switch
        Mount1Label                   matlab.ui.control.Label
        edit_mount1oriupLabel         matlab.ui.control.Label
        edit_mount1oriup              matlab.ui.control.EditField
        edit_mount1orilowLabel        matlab.ui.control.Label
        edit_mount1orilow             matlab.ui.control.EditField
        edit_mount1orientationLabel   matlab.ui.control.Label
        edit_mount1orientation        matlab.ui.control.EditField
        Mount2Label                   matlab.ui.control.Label
        edit_mount2oriupLabel         matlab.ui.control.Label
        edit_mount2oriup              matlab.ui.control.EditField
        edit_mount2orilowLabel        matlab.ui.control.Label
        edit_mount2orilow             matlab.ui.control.EditField
        sw_mount2ori                  matlab.ui.control.Switch
        edit_mount2orientationLabel   matlab.ui.control.Label
        edit_mount2orientation        matlab.ui.control.EditField
        Mount3Label                   matlab.ui.control.Label
        edit_mount3oriupLabel         matlab.ui.control.Label
        edit_mount3oriup              matlab.ui.control.EditField
        edit_mount3orilowLabel        matlab.ui.control.Label
        edit_mount3orilow             matlab.ui.control.EditField
        sw_mount3ori                  matlab.ui.control.Switch
        edit_mount3orientationLabel   matlab.ui.control.Label
        edit_mount3orientation        matlab.ui.control.EditField
        panel_con_location            matlab.ui.container.Panel
        sw_mount1loc                  matlab.ui.control.Switch
        Mount1Label_2                 matlab.ui.control.Label
        edit_mount1locupLabel         matlab.ui.control.Label
        edit_mount1locup              matlab.ui.control.EditField
        edit_mount1loclowLabel        matlab.ui.control.Label
        edit_mount1loclow             matlab.ui.control.EditField
        SymValforxCheckBox            matlab.ui.control.CheckBox
        SymValforyCheckBox            matlab.ui.control.CheckBox
        SymValforzCheckBox            matlab.ui.control.CheckBox
        edit_mount1positionLabel      matlab.ui.control.Label
        edit_mount1position           matlab.ui.control.EditField
        Mount2Label_2                 matlab.ui.control.Label
        edit_mount2locupLabel         matlab.ui.control.Label
        edit_mount2locup              matlab.ui.control.EditField
        edit_mount2loclowLabel        matlab.ui.control.Label
        edit_mount2loclow             matlab.ui.control.EditField
        sw_mount2loc                  matlab.ui.control.Switch
        SymValforxCheckBox_2          matlab.ui.control.CheckBox
        SymValforyCheckBox_2          matlab.ui.control.CheckBox
        SymValforzCheckBox_2          matlab.ui.control.CheckBox
        edit_mount2positionLabel      matlab.ui.control.Label
        edit_mount2position           matlab.ui.control.EditField
        Mount3Label_2                 matlab.ui.control.Label
        edit_mount3locupLabel         matlab.ui.control.Label
        edit_mount3locup              matlab.ui.control.EditField
        edit_mount3loclowLabel        matlab.ui.control.Label
        edit_mount3loclow             matlab.ui.control.EditField
        sw_mount3loc                  matlab.ui.control.Switch
        SymValforxCheckBox_3          matlab.ui.control.CheckBox
        SymValforyCheckBox_3          matlab.ui.control.CheckBox
        SymValforzCheckBox_3          matlab.ui.control.CheckBox
        edit_mount3positionLabel      matlab.ui.control.Label
        edit_mount3position           matlab.ui.control.EditField
        StiffnessTab                  matlab.ui.container.Tab
        panel_mount1e                 matlab.ui.container.Panel
        InitialStiffnessLabel         matlab.ui.control.Label
        edit_mount1stiff              matlab.ui.control.EditField
        Label17                       matlab.ui.control.Label
        sw_mount1proe                 matlab.ui.control.Switch
        edit_mount1proeupLabel        matlab.ui.control.Label
        edit_mount1proeup             matlab.ui.control.EditField
        edit_mount1proelowLabel       matlab.ui.control.Label
        edit_mount1proelow            matlab.ui.control.EditField
        Label17_2                     matlab.ui.control.Label
        Label17_3                     matlab.ui.control.Label
        StiffnessLabel                matlab.ui.control.Label
        Label17_4                     matlab.ui.control.Label
        ethaEditFieldLabel            matlab.ui.control.Label
        ethaEditField                 matlab.ui.control.NumericEditField
        sw_mount1proe_2               matlab.ui.control.Switch
        edit_mount1proeupLabel_2      matlab.ui.control.Label
        edit_mount1proeup_2           matlab.ui.control.EditField
        edit_mount1proelowLabel_2     matlab.ui.control.Label
        edit_mount1proelow_2          matlab.ui.control.EditField
        DampingLabel                  matlab.ui.control.Label
        InitialStiffnessLabel_2       matlab.ui.control.Label
        edit_mount1stiff_2            matlab.ui.control.EditField
        Label17_5                     matlab.ui.control.Label
        Label17_6                     matlab.ui.control.Label
        ProportionalCheckBox          matlab.ui.control.CheckBox
        panel_mount1e_2               matlab.ui.container.Panel
        InitialStiffnessLabel_3       matlab.ui.control.Label
        edit_mount1stiff_3            matlab.ui.control.EditField
        Label17_7                     matlab.ui.control.Label
        sw_mount1proe_3               matlab.ui.control.Switch
        edit_mount1proeupLabel_3      matlab.ui.control.Label
        edit_mount1proeup_3           matlab.ui.control.EditField
        edit_mount1proelowLabel_3     matlab.ui.control.Label
        edit_mount1proelow_3          matlab.ui.control.EditField
        Label17_8                     matlab.ui.control.Label
        Label17_9                     matlab.ui.control.Label
        StiffnessLabel_2              matlab.ui.control.Label
        Label17_10                    matlab.ui.control.Label
        ethaEditField_2Label          matlab.ui.control.Label
        ethaEditField_2               matlab.ui.control.NumericEditField
        sw_mount1proe_4               matlab.ui.control.Switch
        edit_mount1proeupLabel_4      matlab.ui.control.Label
        edit_mount1proeup_4           matlab.ui.control.EditField
        edit_mount1proelowLabel_4     matlab.ui.control.Label
        edit_mount1proelow_4          matlab.ui.control.EditField
        DampingLabel_2                matlab.ui.control.Label
        InitialStiffnessLabel_4       matlab.ui.control.Label
        edit_mount1stiff_4            matlab.ui.control.EditField
        Label17_11                    matlab.ui.control.Label
        Label17_12                    matlab.ui.control.Label
        ProportionalCheckBox_2        matlab.ui.control.CheckBox
        panel_mount1e_3               matlab.ui.container.Panel
        InitialStiffnessLabel_5       matlab.ui.control.Label
        edit_mount1stiff_5            matlab.ui.control.EditField
        Label17_13                    matlab.ui.control.Label
        sw_mount1proe_5               matlab.ui.control.Switch
        edit_mount1proeupLabel_5      matlab.ui.control.Label
        edit_mount1proeup_5           matlab.ui.control.EditField
        edit_mount1proelowLabel_5     matlab.ui.control.Label
        edit_mount1proelow_5          matlab.ui.control.EditField
        Label17_14                    matlab.ui.control.Label
        Label17_15                    matlab.ui.control.Label
        StiffnessLabel_3              matlab.ui.control.Label
        Label17_16                    matlab.ui.control.Label
        ethaEditField_3Label          matlab.ui.control.Label
        ethaEditField_3               matlab.ui.control.NumericEditField
        sw_mount1proe_6               matlab.ui.control.Switch
        edit_mount1proeupLabel_6      matlab.ui.control.Label
        edit_mount1proeup_6           matlab.ui.control.EditField
        edit_mount1proelowLabel_6     matlab.ui.control.Label
        edit_mount1proelow_6          matlab.ui.control.EditField
        DampingLabel_3                matlab.ui.control.Label
        InitialStiffnessLabel_6       matlab.ui.control.Label
        edit_mount1stiff_6            matlab.ui.control.EditField
        Label17_17                    matlab.ui.control.Label
        Label17_18                    matlab.ui.control.Label
        ProportionalCheckBox_3        matlab.ui.control.CheckBox
        SaveButton                    matlab.ui.control.Button
        InputParametersLabel          matlab.ui.control.Label
        CloseButton                   matlab.ui.control.Button
        InputNameEditFieldLabel       matlab.ui.control.Label
        InputNameEditField            matlab.ui.control.EditField
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load('gui_curr.mat');
            
                gui_curr.input_name
                load(['SavedResults/',gui_curr.input_name]);
                app.InputNameEditField.Value = gui_curr.input_name;
                app.InputNameEditField.Editable = 'off';
                save('SavedResults/temp_inpp.mat')
                %                 sus.ChassisID = ChasisIDEditField.Value;
            app.I_xxEditField.Value = sus.Isxx;
            app.I_yyEditField.Value = sus.Isyy;
            app.SprungMassEditField.Value = sus.Ms;
            app.UnsprungMassEditField.Value = sus.Mu1;%front
            app.UnsprungMassEditField.Value = sus.Mu4;%front
            app.UnsprungMassEditField_2.Value = sus.Mu3;%rear
            app.UnsprungMassEditField_2.Value = sus.Mu2;%rear
            app.StiffnessEditField.Value = sus.ks1;
            app.StiffnessEditField.Value = sus.ks4;
            app.StiffnessEditField_2.Value = sus.ks3;
            app.StiffnessEditField_2.Value = sus.ks2;
            app.DampingEditField.Value = sus.cs1;
            app.DampingEditField.Value = sus.cs4;
            app.DampingEditField_2.Value = sus.cs3;
            app.DampingEditField_2.Value = sus.cs2;
            app.StiffnessEditField_3.Value = sus.kt1;
            app.StiffnessEditField_3.Value = sus.kt2;
            app.StiffnessEditField_3.Value = sus.kt3;
            app.StiffnessEditField_3.Value = sus.kt4;
            
            %             app.EngineNameEditField.Value = eng.name;
            %             app.EngineModelEditField.Value = eng.model;
            app.MasskgEditField.Value = eng.m;
            load('temp.mat');temp.inertia = eng.I;save('temp.mat','temp');
            app.IdlespeedEditField.Value = eng.idle_speed;
            app.TorqueIdlespeedEditField_2.Value = eng.max_torque;


        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app)
        end

        % Callback function
        function LocationOrientaionButtonPushed(app, event)
            gui_loc_orie
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            
                
           load(['SavedResults/','temp_inpp.mat']);
             
            
            sus.ChassisID = app.ChasisIDEditField.Value;
            sus.Isxx = app.I_xxEditField.Value;
            sus.Isyy = app.I_yyEditField.Value;
            sus.Ms = app.SprungMassEditField.Value;
            sus.Mu1 = app.UnsprungMassEditField.Value;%front
            sus.Mu4 = app.UnsprungMassEditField.Value;%front
            sus.Mu3 = app.UnsprungMassEditField_2.Value;%rear
            sus.Mu2 = app.UnsprungMassEditField_2.Value;%rear
            sus.ks1 = app.StiffnessEditField.Value;
            sus.ks4 = app.StiffnessEditField.Value;
            sus.ks3 = app.StiffnessEditField_2.Value;
            sus.ks2 = app.StiffnessEditField_2.Value;
            sus.cs1 = app.DampingEditField.Value;
            sus.cs4 = app.DampingEditField.Value;
            sus.cs3 = app.DampingEditField_2.Value;
            sus.cs2 = app.DampingEditField_2.Value;
            sus.kt1 = app.StiffnessEditField_3.Value;
            sus.kt2 = app.StiffnessEditField_3.Value;
            sus.kt3 = app.StiffnessEditField_3.Value;
            sus.kt4 = app.StiffnessEditField_3.Value;
            
            eng.name = app.EngineNameEditField.Value;
            eng.model = app.EngineModelEditField.Value;
            eng.mass = app.MasskgEditField.Value;
            eng.I = inertia;
            eng.idle_speed = app.IdlespeedEditField.Value;
            eng.max_torque = app.TorqueIdlespeedEditField_2.Value;
            
            save(['SavedResults/',gui_curr.input_name],'sus','eng');
            
            
        end

        % Callback function
        function SetInertiaButtonPushed(app, event)
            gui_eng_inertia
        end

        % Button pushed function: SetinertiaButton
        function SetinertiaButtonPushed(app, event)
            gui_eng_inertia
            
        end

        % Callback function
        function StiffnessDampingButtonPushed(app, event)
            gui_stiffness
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.InputParametersUIFigure = uifigure;
            app.InputParametersUIFigure.Name = 'Input Parameters';
            app.TabGroup2 = uitabgroup(app.InputParametersUIFigure);
            app.BodyTab = uitab(app.TabGroup2);
            app.BodyTab.Title = 'Body';
            app.FrontSuspensionPanel = uipanel(app.BodyTab);
            app.FrontSuspensionPanel.Title = 'Front Suspension';
            app.UnsprungMassEditFieldLabel = uilabel(app.FrontSuspensionPanel);
            app.UnsprungMassEditFieldLabel.Text = 'Unsprung Mass';
            app.UnsprungMassEditField = uieditfield(app.FrontSuspensionPanel, 'numeric');
            app.DampingEditFieldLabel = uilabel(app.FrontSuspensionPanel);
            app.DampingEditFieldLabel.Text = 'Damping';
            app.DampingEditField = uieditfield(app.FrontSuspensionPanel, 'numeric');
            app.StiffnessEditFieldLabel = uilabel(app.FrontSuspensionPanel);
            app.StiffnessEditFieldLabel.Text = 'Stiffness';
            app.StiffnessEditField = uieditfield(app.FrontSuspensionPanel, 'numeric');
            app.SprungMassPanel = uipanel(app.BodyTab);
            app.SprungMassPanel.Title = 'Sprung Mass';
            app.ChasisIDEditFieldLabel = uilabel(app.SprungMassPanel);
            app.ChasisIDEditFieldLabel.Text = 'ChasisID';
            app.ChasisIDEditField = uieditfield(app.SprungMassPanel, 'text');
            app.ChasisIDEditField.Value = '123456';
            app.SprungMassEditFieldLabel = uilabel(app.SprungMassPanel);
            app.SprungMassEditFieldLabel.Text = 'Sprung Mass';
            app.SprungMassEditField = uieditfield(app.SprungMassPanel, 'numeric');
            app.DefineCoordinateButton = uibutton(app.SprungMassPanel, 'push');
            app.DefineCoordinateButton.Text = 'Define Coordinate';
            app.I_xxEditFieldLabel = uilabel(app.SprungMassPanel);
            app.I_xxEditFieldLabel.Text = 'I_xx';
            app.I_xxEditField = uieditfield(app.SprungMassPanel, 'numeric');
            app.I_yyEditFieldLabel = uilabel(app.SprungMassPanel);
            app.I_yyEditFieldLabel.Text = 'I_yy';
            app.I_yyEditField = uieditfield(app.SprungMassPanel, 'numeric');
            app.WheelPositionButton = uibutton(app.SprungMassPanel, 'push');
            app.WheelPositionButton.Text = 'Wheel Position';
            app.RearSuspensionPanel = uipanel(app.BodyTab);
            app.RearSuspensionPanel.Title = 'Rear Suspension';
            app.UnsprungMassEditField_2Label = uilabel(app.RearSuspensionPanel);
            app.UnsprungMassEditField_2Label.Text = 'Unsprung Mass';
            app.UnsprungMassEditField_2 = uieditfield(app.RearSuspensionPanel, 'numeric');
            app.DampingEditField_2Label = uilabel(app.RearSuspensionPanel);
            app.DampingEditField_2Label.Text = 'Damping';
            app.DampingEditField_2 = uieditfield(app.RearSuspensionPanel, 'numeric');
            app.StiffnessEditField_2Label = uilabel(app.RearSuspensionPanel);
            app.StiffnessEditField_2Label.Text = 'Stiffness';
            app.StiffnessEditField_2 = uieditfield(app.RearSuspensionPanel, 'numeric');
            app.TirePanel = uipanel(app.BodyTab);
            app.TirePanel.Title = 'Tire';
            app.ModelEditFieldLabel = uilabel(app.TirePanel);
            app.ModelEditFieldLabel.Text = 'Model';
            app.ModelEditField = uieditfield(app.TirePanel, 'text');
            app.StiffnessEditField_3Label = uilabel(app.TirePanel);
            app.StiffnessEditField_3Label.Text = 'Stiffness';
            app.StiffnessEditField_3 = uieditfield(app.TirePanel, 'numeric');
            app.EngineTab = uitab(app.TabGroup2);
            app.EngineTab.Title = 'Engine';
            app.EngineNameEditFieldLabel = uilabel(app.EngineTab);
            app.EngineNameEditFieldLabel.Text = 'Engine Name';
            app.EngineNameEditField = uieditfield(app.EngineTab, 'text');
            app.EngineNameEditField.Value = 'saipa_pride';
            app.MasskgEditFieldLabel = uilabel(app.EngineTab);
            app.MasskgEditFieldLabel.Text = 'Mass (kg)';
            app.MasskgEditField = uieditfield(app.EngineTab, 'numeric');
            app.SetinertiaButton = uibutton(app.EngineTab, 'push');
            app.SetinertiaButton.ButtonPushedFcn = createCallbackFcn(app, @SetinertiaButtonPushed, true);
            app.SetinertiaButton.Text = 'Set inertia ...';
            app.Inertiamatrixkgm2EditFieldLabel = uilabel(app.EngineTab);
            app.Inertiamatrixkgm2EditFieldLabel.Text = 'Inertia matrix (kg.m^2)';
            app.Inertiamatrixkgm2EditField = uieditfield(app.EngineTab, 'numeric');
            app.IdlespeedEditFieldLabel = uilabel(app.EngineTab);
            app.IdlespeedEditFieldLabel.Text = 'Idle speed';
            app.IdlespeedEditField = uieditfield(app.EngineTab, 'numeric');
            app.TorqueIdlespeedEditField_2Label = uilabel(app.EngineTab);
            app.TorqueIdlespeedEditField_2Label.Text = 'Torque @ Idle speed';
            app.TorqueIdlespeedEditField_2 = uieditfield(app.EngineTab, 'numeric');
            app.EngineModelEditFieldLabel = uilabel(app.EngineTab);
            app.EngineModelEditFieldLabel.Text = 'Engine Model';
            app.EngineModelEditField = uieditfield(app.EngineTab, 'text');
            app.EngineModelEditField.Value = 'EF7';
            app.EngineMountsTab = uitab(app.TabGroup2);
            app.EngineMountsTab.Title = 'Engine Mounts';
            app.ConfigurationDropDownLabel = uilabel(app.EngineMountsTab);
            app.ConfigurationDropDownLabel.Text = 'Configuration';
            app.ConfigurationDropDown = uidropdown(app.EngineMountsTab);
            app.ConfigurationDropDown.Items = {'3 Elastomeric', '1 Hydraulic-2 Elastomeric'};
            app.ConfigurationDropDown.Value = '3 Elastomeric';
            app.LocationTab = uitab(app.TabGroup2);
            app.LocationTab.Title = 'Location';
            app.panel_con_orientation = uipanel(app.LocationTab);
            app.panel_con_orientation.Title = 'Orientation';
            app.sw_mount1ori = uiswitch(app.panel_con_orientation, 'slider');
            app.sw_mount1ori.Items = {'Bounded', 'Fixed'};
            app.sw_mount1ori.Value = 'Bounded';
            app.Mount1Label = uilabel(app.panel_con_orientation);
            app.Mount1Label.Text = 'Mount 1';
            app.edit_mount1oriupLabel = uilabel(app.panel_con_orientation);
            app.edit_mount1oriupLabel.Text = 'Upper Bound:';
            app.edit_mount1oriup = uieditfield(app.panel_con_orientation, 'text');
            app.edit_mount1oriup.Value = '[90, 90, 90]';
            app.edit_mount1orilowLabel = uilabel(app.panel_con_orientation);
            app.edit_mount1orilowLabel.Text = 'Lower Bound:';
            app.edit_mount1orilow = uieditfield(app.panel_con_orientation, 'text');
            app.edit_mount1orilow.Value = '[0, 0, 0]';
            app.edit_mount1orientationLabel = uilabel(app.panel_con_orientation);
            app.edit_mount1orientationLabel.Text = 'Fixed Orientation:';
            app.edit_mount1orientation = uieditfield(app.panel_con_orientation, 'text');
            app.edit_mount1orientation.Value = '[0, 0, 0]';
            app.Mount2Label = uilabel(app.panel_con_orientation);
            app.Mount2Label.Text = 'Mount 2';
            app.edit_mount2oriupLabel = uilabel(app.panel_con_orientation);
            app.edit_mount2oriupLabel.Text = 'Upper Bound:';
            app.edit_mount2oriup = uieditfield(app.panel_con_orientation, 'text');
            app.edit_mount2oriup.Value = '[90, 90, 90]';
            app.edit_mount2orilowLabel = uilabel(app.panel_con_orientation);
            app.edit_mount2orilowLabel.Text = 'Lower Bound:';
            app.edit_mount2orilow = uieditfield(app.panel_con_orientation, 'text');
            app.edit_mount2orilow.Value = '[0, 0, 0]';
            app.sw_mount2ori = uiswitch(app.panel_con_orientation, 'slider');
            app.sw_mount2ori.Items = {'Bounded', 'Fixed'};
            app.sw_mount2ori.Value = 'Bounded';
            app.edit_mount2orientationLabel = uilabel(app.panel_con_orientation);
            app.edit_mount2orientationLabel.Text = 'Fixed Orientation:';
            app.edit_mount2orientation = uieditfield(app.panel_con_orientation, 'text');
            app.edit_mount2orientation.Value = '[0, 0, 0]';
            app.Mount3Label = uilabel(app.panel_con_orientation);
            app.Mount3Label.Text = 'Mount 3';
            app.edit_mount3oriupLabel = uilabel(app.panel_con_orientation);
            app.edit_mount3oriupLabel.Text = 'Upper Bound:';
            app.edit_mount3oriup = uieditfield(app.panel_con_orientation, 'text');
            app.edit_mount3oriup.Value = '[90, 90, 90]';
            app.edit_mount3orilowLabel = uilabel(app.panel_con_orientation);
            app.edit_mount3orilowLabel.Text = 'Lower Bound:';
            app.edit_mount3orilow = uieditfield(app.panel_con_orientation, 'text');
            app.edit_mount3orilow.Value = '[0, 0, 0]';
            app.sw_mount3ori = uiswitch(app.panel_con_orientation, 'slider');
            app.sw_mount3ori.Items = {'Bounded', 'Fixed'};
            app.sw_mount3ori.Value = 'Bounded';
            app.edit_mount3orientationLabel = uilabel(app.panel_con_orientation);
            app.edit_mount3orientationLabel.Text = 'Fixed Orientation:';
            app.edit_mount3orientation = uieditfield(app.panel_con_orientation, 'text');
            app.edit_mount3orientation.Value = '[0, 0, 0]';
            app.panel_con_location = uipanel(app.LocationTab);
            app.panel_con_location.Title = 'Location';
            app.sw_mount1loc = uiswitch(app.panel_con_location, 'slider');
            app.sw_mount1loc.Items = {'Bounded', 'Fixed'};
            app.sw_mount1loc.Value = 'Bounded';
            app.Mount1Label_2 = uilabel(app.panel_con_location);
            app.Mount1Label_2.Text = 'Mount 1';
            app.edit_mount1locupLabel = uilabel(app.panel_con_location);
            app.edit_mount1locupLabel.Text = 'Upper Bound:';
            app.edit_mount1locup = uieditfield(app.panel_con_location, 'text');
            app.edit_mount1locup.Value = '[200, 380, 215]';
            app.edit_mount1loclowLabel = uilabel(app.panel_con_location);
            app.edit_mount1loclowLabel.Text = 'Lower Bound:';
            app.edit_mount1loclow = uieditfield(app.panel_con_location, 'text');
            app.edit_mount1loclow.Value = '[-250, -400, -50]';
            app.SymValforxCheckBox = uicheckbox(app.panel_con_location);
            app.SymValforxCheckBox.Visible = 'off';
            app.SymValforxCheckBox.Text = 'Sym. Val. for x';
            app.SymValforyCheckBox = uicheckbox(app.panel_con_location);
            app.SymValforyCheckBox.Visible = 'off';
            app.SymValforyCheckBox.Text = 'Sym. Val. for y';
            app.SymValforzCheckBox = uicheckbox(app.panel_con_location);
            app.SymValforzCheckBox.Visible = 'off';
            app.SymValforzCheckBox.Text = 'Sym. Val. for z';
            app.edit_mount1positionLabel = uilabel(app.panel_con_location);
            app.edit_mount1positionLabel.Text = 'Fixed Position:';
            app.edit_mount1position = uieditfield(app.panel_con_location, 'text');
            app.edit_mount1position.Value = '[13.5, -389, 203]';
            app.Mount2Label_2 = uilabel(app.panel_con_location);
            app.Mount2Label_2.Text = 'Mount 2';
            app.edit_mount2locupLabel = uilabel(app.panel_con_location);
            app.edit_mount2locupLabel.Text = 'Upper Bound:';
            app.edit_mount2locup = uieditfield(app.panel_con_location, 'text');
            app.edit_mount2locup.Value = '[-415, 390, -170]';
            app.edit_mount2loclowLabel = uilabel(app.panel_con_location);
            app.edit_mount2loclowLabel.Text = 'Lower Bound:';
            app.edit_mount2loclow = uieditfield(app.panel_con_location, 'text');
            app.edit_mount2loclow.Value = '[-430, -210, -180]';
            app.sw_mount2loc = uiswitch(app.panel_con_location, 'slider');
            app.sw_mount2loc.Items = {'Bounded', 'Fixed'};
            app.sw_mount2loc.Value = 'Bounded';
            app.SymValforxCheckBox_2 = uicheckbox(app.panel_con_location);
            app.SymValforxCheckBox_2.Visible = 'off';
            app.SymValforxCheckBox_2.Text = 'Sym. Val. for x';
            app.SymValforyCheckBox_2 = uicheckbox(app.panel_con_location);
            app.SymValforyCheckBox_2.Visible = 'off';
            app.SymValforyCheckBox_2.Text = 'Sym. Val. for y';
            app.SymValforzCheckBox_2 = uicheckbox(app.panel_con_location);
            app.SymValforzCheckBox_2.Visible = 'off';
            app.SymValforzCheckBox_2.Text = 'Sym. Val. for z';
            app.edit_mount2positionLabel = uilabel(app.panel_con_location);
            app.edit_mount2positionLabel.Text = 'Fixed Position:';
            app.edit_mount2position = uieditfield(app.panel_con_location, 'text');
            app.edit_mount2position.Value = '[-424, 92, -175]';
            app.Mount3Label_2 = uilabel(app.panel_con_location);
            app.Mount3Label_2.Text = 'Mount 3';
            app.edit_mount3locupLabel = uilabel(app.panel_con_location);
            app.edit_mount3locupLabel.Text = 'Upper Bound:';
            app.edit_mount3locup = uieditfield(app.panel_con_location, 'text');
            app.edit_mount3locup.Value = '[190, 460, 210]';
            app.edit_mount3loclowLabel = uilabel(app.panel_con_location);
            app.edit_mount3loclowLabel.Text = 'Lower Bound:';
            app.edit_mount3loclow = uieditfield(app.panel_con_location, 'text');
            app.edit_mount3loclow.Value = '[-250, 450, -70]';
            app.sw_mount3loc = uiswitch(app.panel_con_location, 'slider');
            app.sw_mount3loc.Items = {'Bounded', 'Fixed'};
            app.sw_mount3loc.Value = 'Bounded';
            app.SymValforxCheckBox_3 = uicheckbox(app.panel_con_location);
            app.SymValforxCheckBox_3.Visible = 'off';
            app.SymValforxCheckBox_3.Text = 'Sym. Val. for x';
            app.SymValforyCheckBox_3 = uicheckbox(app.panel_con_location);
            app.SymValforyCheckBox_3.Visible = 'off';
            app.SymValforyCheckBox_3.Text = 'Sym. Val. for y';
            app.SymValforzCheckBox_3 = uicheckbox(app.panel_con_location);
            app.SymValforzCheckBox_3.Visible = 'off';
            app.SymValforzCheckBox_3.Text = 'Sym. Val. for z';
            app.edit_mount3positionLabel = uilabel(app.panel_con_location);
            app.edit_mount3positionLabel.Text = 'Fixed Position:';
            app.edit_mount3position = uieditfield(app.panel_con_location, 'text');
            app.edit_mount3position.Value = '[182, 454, -68]';
            app.StiffnessTab = uitab(app.TabGroup2);
            app.StiffnessTab.Title = 'Stiffness';
            app.panel_mount1e = uipanel(app.StiffnessTab);
            app.panel_mount1e.Title = 'Mount 1 (Elastomeric)';
            app.InitialStiffnessLabel = uilabel(app.panel_mount1e);
            app.InitialStiffnessLabel.Text = 'Initial Stiffness:';
            app.edit_mount1stiff = uieditfield(app.panel_mount1e, 'text');
            app.edit_mount1stiff.Value = '[18.2, 40, 31]';
            app.Label17 = uilabel(app.panel_mount1e);
            app.Label17.Text = 'N/mm';
            app.sw_mount1proe = uiswitch(app.panel_mount1e, 'slider');
            app.sw_mount1proe.Items = {'Bounded', 'Fixed'};
            app.sw_mount1proe.Value = 'Bounded';
            app.edit_mount1proeupLabel = uilabel(app.panel_mount1e);
            app.edit_mount1proeupLabel.Text = 'Upper Bound:';
            app.edit_mount1proeup = uieditfield(app.panel_mount1e, 'text');
            app.edit_mount1proeup.Value = '[30, 50, 40]';
            app.edit_mount1proelowLabel = uilabel(app.panel_mount1e);
            app.edit_mount1proelowLabel.Text = 'Lower Bound:';
            app.edit_mount1proelow = uieditfield(app.panel_mount1e, 'text');
            app.edit_mount1proelow.Value = '[10, 30, 20]';
            app.Label17_2 = uilabel(app.panel_mount1e);
            app.Label17_2.Text = 'N/mm';
            app.Label17_3 = uilabel(app.panel_mount1e);
            app.Label17_3.Text = 'N/mm';
            app.StiffnessLabel = uilabel(app.panel_mount1e);
            app.StiffnessLabel.Text = 'Stiffness';
            app.Label17_4 = uilabel(app.panel_mount1e);
            app.Label17_4.Text = 'N.s/m';
            app.ethaEditFieldLabel = uilabel(app.panel_mount1e);
            app.ethaEditFieldLabel.Text = 'etha';
            app.ethaEditField = uieditfield(app.panel_mount1e, 'numeric');
            app.sw_mount1proe_2 = uiswitch(app.panel_mount1e, 'slider');
            app.sw_mount1proe_2.Items = {'Bounded', 'Fixed'};
            app.sw_mount1proe_2.Value = 'Bounded';
            app.edit_mount1proeupLabel_2 = uilabel(app.panel_mount1e);
            app.edit_mount1proeupLabel_2.Text = 'Upper Bound:';
            app.edit_mount1proeup_2 = uieditfield(app.panel_mount1e, 'text');
            app.edit_mount1proeup_2.Value = '[30, 50, 40]';
            app.edit_mount1proelowLabel_2 = uilabel(app.panel_mount1e);
            app.edit_mount1proelowLabel_2.Text = 'Lower Bound:';
            app.edit_mount1proelow_2 = uieditfield(app.panel_mount1e, 'text');
            app.edit_mount1proelow_2.Value = '[10, 30, 20]';
            app.DampingLabel = uilabel(app.panel_mount1e);
            app.DampingLabel.Text = 'Damping';
            app.InitialStiffnessLabel_2 = uilabel(app.panel_mount1e);
            app.InitialStiffnessLabel_2.Text = 'Initial Stiffness:';
            app.edit_mount1stiff_2 = uieditfield(app.panel_mount1e, 'text');
            app.edit_mount1stiff_2.Value = '[18.2, 40, 31]';
            app.Label17_5 = uilabel(app.panel_mount1e);
            app.Label17_5.Text = 'N.s/m';
            app.Label17_6 = uilabel(app.panel_mount1e);
            app.Label17_6.Text = 'N.s/m';
            app.ProportionalCheckBox = uicheckbox(app.panel_mount1e);
            app.ProportionalCheckBox.Text = 'Proportional';
            app.panel_mount1e_2 = uipanel(app.StiffnessTab);
            app.panel_mount1e_2.Title = 'Mount 2 (Elastomeric)';
            app.InitialStiffnessLabel_3 = uilabel(app.panel_mount1e_2);
            app.InitialStiffnessLabel_3.Text = 'Initial Stiffness:';
            app.edit_mount1stiff_3 = uieditfield(app.panel_mount1e_2, 'text');
            app.edit_mount1stiff_3.Value = '[18.2, 40, 31]';
            app.Label17_7 = uilabel(app.panel_mount1e_2);
            app.Label17_7.Text = 'N/mm';
            app.sw_mount1proe_3 = uiswitch(app.panel_mount1e_2, 'slider');
            app.sw_mount1proe_3.Items = {'Bounded', 'Fixed'};
            app.sw_mount1proe_3.Value = 'Bounded';
            app.edit_mount1proeupLabel_3 = uilabel(app.panel_mount1e_2);
            app.edit_mount1proeupLabel_3.Text = 'Upper Bound:';
            app.edit_mount1proeup_3 = uieditfield(app.panel_mount1e_2, 'text');
            app.edit_mount1proeup_3.Value = '[30, 50, 40]';
            app.edit_mount1proelowLabel_3 = uilabel(app.panel_mount1e_2);
            app.edit_mount1proelowLabel_3.Text = 'Lower Bound:';
            app.edit_mount1proelow_3 = uieditfield(app.panel_mount1e_2, 'text');
            app.edit_mount1proelow_3.Value = '[10, 30, 20]';
            app.Label17_8 = uilabel(app.panel_mount1e_2);
            app.Label17_8.Text = 'N/mm';
            app.Label17_9 = uilabel(app.panel_mount1e_2);
            app.Label17_9.Text = 'N/mm';
            app.StiffnessLabel_2 = uilabel(app.panel_mount1e_2);
            app.StiffnessLabel_2.Text = 'Stiffness';
            app.Label17_10 = uilabel(app.panel_mount1e_2);
            app.Label17_10.Text = 'N.s/m';
            app.ethaEditField_2Label = uilabel(app.panel_mount1e_2);
            app.ethaEditField_2Label.Text = 'etha';
            app.ethaEditField_2 = uieditfield(app.panel_mount1e_2, 'numeric');
            app.sw_mount1proe_4 = uiswitch(app.panel_mount1e_2, 'slider');
            app.sw_mount1proe_4.Items = {'Bounded', 'Fixed'};
            app.sw_mount1proe_4.Value = 'Bounded';
            app.edit_mount1proeupLabel_4 = uilabel(app.panel_mount1e_2);
            app.edit_mount1proeupLabel_4.Text = 'Upper Bound:';
            app.edit_mount1proeup_4 = uieditfield(app.panel_mount1e_2, 'text');
            app.edit_mount1proeup_4.Value = '[30, 50, 40]';
            app.edit_mount1proelowLabel_4 = uilabel(app.panel_mount1e_2);
            app.edit_mount1proelowLabel_4.Text = 'Lower Bound:';
            app.edit_mount1proelow_4 = uieditfield(app.panel_mount1e_2, 'text');
            app.edit_mount1proelow_4.Value = '[10, 30, 20]';
            app.DampingLabel_2 = uilabel(app.panel_mount1e_2);
            app.DampingLabel_2.Text = 'Damping';
            app.InitialStiffnessLabel_4 = uilabel(app.panel_mount1e_2);
            app.InitialStiffnessLabel_4.Text = 'Initial Stiffness:';
            app.edit_mount1stiff_4 = uieditfield(app.panel_mount1e_2, 'text');
            app.edit_mount1stiff_4.Value = '[18.2, 40, 31]';
            app.Label17_11 = uilabel(app.panel_mount1e_2);
            app.Label17_11.Text = 'N.s/m';
            app.Label17_12 = uilabel(app.panel_mount1e_2);
            app.Label17_12.Text = 'N.s/m';
            app.ProportionalCheckBox_2 = uicheckbox(app.panel_mount1e_2);
            app.ProportionalCheckBox_2.Text = 'Proportional';
            app.panel_mount1e_3 = uipanel(app.StiffnessTab);
            app.panel_mount1e_3.Title = 'Mount 1 (Elastomeric)';
            app.InitialStiffnessLabel_5 = uilabel(app.panel_mount1e_3);
            app.InitialStiffnessLabel_5.Text = 'Initial Stiffness:';
            app.edit_mount1stiff_5 = uieditfield(app.panel_mount1e_3, 'text');
            app.edit_mount1stiff_5.Value = '[18.2, 40, 31]';
            app.Label17_13 = uilabel(app.panel_mount1e_3);
            app.Label17_13.Text = 'N/mm';
            app.sw_mount1proe_5 = uiswitch(app.panel_mount1e_3, 'slider');
            app.sw_mount1proe_5.Items = {'Bounded', 'Fixed'};
            app.sw_mount1proe_5.Value = 'Bounded';
            app.edit_mount1proeupLabel_5 = uilabel(app.panel_mount1e_3);
            app.edit_mount1proeupLabel_5.Text = 'Upper Bound:';
            app.edit_mount1proeup_5 = uieditfield(app.panel_mount1e_3, 'text');
            app.edit_mount1proeup_5.Value = '[30, 50, 40]';
            app.edit_mount1proelowLabel_5 = uilabel(app.panel_mount1e_3);
            app.edit_mount1proelowLabel_5.Text = 'Lower Bound:';
            app.edit_mount1proelow_5 = uieditfield(app.panel_mount1e_3, 'text');
            app.edit_mount1proelow_5.Value = '[10, 30, 20]';
            app.Label17_14 = uilabel(app.panel_mount1e_3);
            app.Label17_14.Text = 'N/mm';
            app.Label17_15 = uilabel(app.panel_mount1e_3);
            app.Label17_15.Text = 'N/mm';
            app.StiffnessLabel_3 = uilabel(app.panel_mount1e_3);
            app.StiffnessLabel_3.Text = 'Stiffness';
            app.Label17_16 = uilabel(app.panel_mount1e_3);
            app.Label17_16.Text = 'N.s/m';
            app.ethaEditField_3Label = uilabel(app.panel_mount1e_3);
            app.ethaEditField_3Label.Text = 'etha';
            app.ethaEditField_3 = uieditfield(app.panel_mount1e_3, 'numeric');
            app.sw_mount1proe_6 = uiswitch(app.panel_mount1e_3, 'slider');
            app.sw_mount1proe_6.Items = {'Bounded', 'Fixed'};
            app.sw_mount1proe_6.Value = 'Bounded';
            app.edit_mount1proeupLabel_6 = uilabel(app.panel_mount1e_3);
            app.edit_mount1proeupLabel_6.Text = 'Upper Bound:';
            app.edit_mount1proeup_6 = uieditfield(app.panel_mount1e_3, 'text');
            app.edit_mount1proeup_6.Value = '[30, 50, 40]';
            app.edit_mount1proelowLabel_6 = uilabel(app.panel_mount1e_3);
            app.edit_mount1proelowLabel_6.Text = 'Lower Bound:';
            app.edit_mount1proelow_6 = uieditfield(app.panel_mount1e_3, 'text');
            app.edit_mount1proelow_6.Value = '[10, 30, 20]';
            app.DampingLabel_3 = uilabel(app.panel_mount1e_3);
            app.DampingLabel_3.Text = 'Damping';
            app.InitialStiffnessLabel_6 = uilabel(app.panel_mount1e_3);
            app.InitialStiffnessLabel_6.Text = 'Initial Stiffness:';
            app.edit_mount1stiff_6 = uieditfield(app.panel_mount1e_3, 'text');
            app.edit_mount1stiff_6.Value = '[18.2, 40, 31]';
            app.Label17_17 = uilabel(app.panel_mount1e_3);
            app.Label17_17.Text = 'N.s/m';
            app.Label17_18 = uilabel(app.panel_mount1e_3);
            app.Label17_18.Text = 'N.s/m';
            app.ProportionalCheckBox_3 = uicheckbox(app.panel_mount1e_3);
            app.ProportionalCheckBox_3.Text = 'Proportional';
            app.SaveButton = uibutton(app.InputParametersUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.InputParametersLabel = uilabel(app.InputParametersUIFigure);
            app.InputParametersLabel.Text = 'Input Parameters';
            app.CloseButton = uibutton(app.InputParametersUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.InputNameEditFieldLabel = uilabel(app.InputParametersUIFigure);
            app.InputNameEditFieldLabel.Text = 'Input Name';
            app.InputNameEditField = uieditfield(app.InputParametersUIFigure, 'text');
        end
    end

    methods (Access = public)

        % Construct app
        function app = gui_input_parameters

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.InputParametersUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.InputParametersUIFigure)
        end
    end
end