classdef gui_optimization < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OptimizationUIFigure            matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        StrategyTab                     matlab.ui.container.Tab
        TFPanel_1                       matlab.ui.container.Panel
        CriteriaButtonGroup_4           matlab.ui.container.ButtonGroup
        MinimizemaximumForceButton_2    matlab.ui.control.RadioButton
        MinimizesummationofForcesButton_2  matlab.ui.control.RadioButton
        SelectedDirectionPanel_3        matlab.ui.container.Panel
        LocalXCheckBox_4                matlab.ui.control.CheckBox
        LocalYCheckBox_4                matlab.ui.control.CheckBox
        LocalZCheckBox_4                matlab.ui.control.CheckBox
        MagnitudeCheckBox_4             matlab.ui.control.CheckBox
        TAPanel_1                       matlab.ui.container.Panel
        CriteriaButtonGroup_5           matlab.ui.container.ButtonGroup
        MinimizemaximumAccelerationButton_2  matlab.ui.control.RadioButton
        MinimizesummationofAccelerationsButton_2  matlab.ui.control.RadioButton
        SelectedDirectionPanel_4        matlab.ui.container.Panel
        LocalXCheckBox_5                matlab.ui.control.CheckBox
        LocalYCheckBox_5                matlab.ui.control.CheckBox
        LocalZCheckBox_5                matlab.ui.control.CheckBox
        MagnitudeCheckBox_5             matlab.ui.control.CheckBox
        ArPanel_1                       matlab.ui.container.Panel
        CriteriaButtonGroup_6           matlab.ui.container.ButtonGroup
        MinimizemaximumAccelerationratioButton_2  matlab.ui.control.RadioButton
        MinimizesummationofAccelerationratiosButton_2  matlab.ui.control.RadioButton
        SelectedDirectionsPanel_2       matlab.ui.container.Panel
        LocalXCheckBox_6                matlab.ui.control.CheckBox
        LocalYCheckBox_6                matlab.ui.control.CheckBox
        LocalZCheckBox_6                matlab.ui.control.CheckBox
        MagnitudeCheckBox_6             matlab.ui.control.CheckBox
        Stage1DropDownLabel             matlab.ui.control.Label
        Stage1DropDown                  matlab.ui.control.DropDown
        Stage2DropDownLabel             matlab.ui.control.Label
        Stage2DropDown                  matlab.ui.control.DropDown
        Stage3DropDownLabel             matlab.ui.control.Label
        Stage3DropDown                  matlab.ui.control.DropDown
        TAPanel_2                       matlab.ui.container.Panel
        CriteriaButtonGroup_7           matlab.ui.container.ButtonGroup
        MinimizemaximumAccelerationButton_3  matlab.ui.control.RadioButton
        MinimizesummationofAccelerationsButton_3  matlab.ui.control.RadioButton
        SelectedDirectionPanel_5        matlab.ui.container.Panel
        LocalXCheckBox_7                matlab.ui.control.CheckBox
        LocalYCheckBox_7                matlab.ui.control.CheckBox
        LocalZCheckBox_7                matlab.ui.control.CheckBox
        MagnitudeCheckBox_7             matlab.ui.control.CheckBox
        TAPanel_3                       matlab.ui.container.Panel
        CriteriaButtonGroup_8           matlab.ui.container.ButtonGroup
        MinimizemaximumAccelerationButton_4  matlab.ui.control.RadioButton
        MinimizesummationofAccelerationsButton_4  matlab.ui.control.RadioButton
        SelectedDirectionPanel_6        matlab.ui.container.Panel
        LocalXCheckBox_8                matlab.ui.control.CheckBox
        LocalYCheckBox_8                matlab.ui.control.CheckBox
        LocalZCheckBox_8                matlab.ui.control.CheckBox
        MagnitudeCheckBox_8             matlab.ui.control.CheckBox
        TFPanel_2                       matlab.ui.container.Panel
        CriteriaButtonGroup_9           matlab.ui.container.ButtonGroup
        MinimizemaximumForceButton_3    matlab.ui.control.RadioButton
        MinimizesummationofForcesButton_3  matlab.ui.control.RadioButton
        SelectedDirectionPanel_7        matlab.ui.container.Panel
        LocalXCheckBox_9                matlab.ui.control.CheckBox
        LocalYCheckBox_9                matlab.ui.control.CheckBox
        LocalZCheckBox_9                matlab.ui.control.CheckBox
        MagnitudeCheckBox_9             matlab.ui.control.CheckBox
        TFPanel_3                       matlab.ui.container.Panel
        CriteriaButtonGroup_10          matlab.ui.container.ButtonGroup
        MinimizemaximumForceButton_4    matlab.ui.control.RadioButton
        MinimizesummationofForcesButton_4  matlab.ui.control.RadioButton
        SelectedDirectionPanel_8        matlab.ui.container.Panel
        LocalXCheckBox_10               matlab.ui.control.CheckBox
        LocalYCheckBox_10               matlab.ui.control.CheckBox
        LocalZCheckBox_10               matlab.ui.control.CheckBox
        MagnitudeCheckBox_10            matlab.ui.control.CheckBox
        ArPanel_2                       matlab.ui.container.Panel
        CriteriaButtonGroup_11          matlab.ui.container.ButtonGroup
        MinimizemaximumAccelerationratioButton_3  matlab.ui.control.RadioButton
        MinimizesummationofAccelerationratiosButton_3  matlab.ui.control.RadioButton
        SelectedDirectionsPanel_3       matlab.ui.container.Panel
        LocalXCheckBox_11               matlab.ui.control.CheckBox
        LocalYCheckBox_11               matlab.ui.control.CheckBox
        LocalZCheckBox_11               matlab.ui.control.CheckBox
        MagnitudeCheckBox_11            matlab.ui.control.CheckBox
        ArPanel_3                       matlab.ui.container.Panel
        CriteriaButtonGroup_12          matlab.ui.container.ButtonGroup
        MinimizemaximumAccelerationratioButton_4  matlab.ui.control.RadioButton
        MinimizesummationofAccelerationratiosButton_4  matlab.ui.control.RadioButton
        SelectedDirectionsPanel_4       matlab.ui.container.Panel
        LocalXCheckBox_12               matlab.ui.control.CheckBox
        LocalYCheckBox_12               matlab.ui.control.CheckBox
        LocalZCheckBox_12               matlab.ui.control.CheckBox
        MagnitudeCheckBox_12            matlab.ui.control.CheckBox
        ConstraintsTab_2                matlab.ui.container.Tab
        KEDcriteriaCheckBox             matlab.ui.control.CheckBox
        percentageEditFieldLabel        matlab.ui.control.Label
        percentageEditField             matlab.ui.control.NumericEditField
        MountloactionorientationButton  matlab.ui.control.Button
        NaturalFrequenciesPanel         matlab.ui.container.Panel
        LongitudinalLabel_3             matlab.ui.control.Label
        LateralLabel_4                  matlab.ui.control.Label
        BounceLabel_4                   matlab.ui.control.Label
        RollLabel_4                     matlab.ui.control.Label
        PitchLabel_4                    matlab.ui.control.Label
        BounceLabel_5                   matlab.ui.control.Label
        EditField_31                    matlab.ui.control.NumericEditField
        LowerHzEditFieldLabel_2         matlab.ui.control.Label
        EditField_32                    matlab.ui.control.NumericEditField
        UpperHzEditFieldLabel_2         matlab.ui.control.Label
        EditField_33                    matlab.ui.control.NumericEditField
        EditField_34                    matlab.ui.control.NumericEditField
        EditField_35                    matlab.ui.control.NumericEditField
        EditField_36                    matlab.ui.control.NumericEditField
        EditField_37                    matlab.ui.control.NumericEditField
        EditField_38                    matlab.ui.control.NumericEditField
        EditField_39                    matlab.ui.control.NumericEditField
        EditField_40                    matlab.ui.control.NumericEditField
        EditField_41                    matlab.ui.control.NumericEditField
        EditField_42                    matlab.ui.control.NumericEditField
        StaticDeflectionsPanel          matlab.ui.container.Panel
        LongitudinalLabel_2             matlab.ui.control.Label
        mmLabel_3                       matlab.ui.control.Label
        LateralLabel_3                  matlab.ui.control.Label
        mmLabel_4                       matlab.ui.control.Label
        BounceLabel_3                   matlab.ui.control.Label
        mmLabel_5                       matlab.ui.control.Label
        RollLabel_3                     matlab.ui.control.Label
        degLabel_4                      matlab.ui.control.Label
        PitchLabel_3                    matlab.ui.control.Label
        degLabel_5                      matlab.ui.control.Label
        YawLabel_3                      matlab.ui.control.Label
        degLabel_6                      matlab.ui.control.Label
        EditField_25                    matlab.ui.control.NumericEditField
        EditField_26                    matlab.ui.control.NumericEditField
        EditField_27                    matlab.ui.control.NumericEditField
        EditField_28                    matlab.ui.control.NumericEditField
        EditField_29                    matlab.ui.control.NumericEditField
        EditField_30                    matlab.ui.control.NumericEditField
        OptionsTab                      matlab.ui.container.Tab
        Stage1Panel_2                   matlab.ui.container.Panel
        swarmsizeEditField_4Label       matlab.ui.control.Label
        swarmsizeEditField_4            matlab.ui.control.NumericEditField
        MaxFuncEvalEditField_4Label     matlab.ui.control.Label
        MaxFuncEvalEditField_4          matlab.ui.control.NumericEditField
        FuncTolEditField_4Label         matlab.ui.control.Label
        FuncTolEditField_4              matlab.ui.control.NumericEditField
        MaxIterEditField_4Label         matlab.ui.control.Label
        MaxIterEditField_4              matlab.ui.control.NumericEditField
        PenFuncWeightEditField_4Label   matlab.ui.control.Label
        PenFuncWeightEditField_4        matlab.ui.control.NumericEditField
        Stage2Panel_2                   matlab.ui.container.Panel
        swarmsizeEditField_5Label       matlab.ui.control.Label
        swarmsizeEditField_5            matlab.ui.control.NumericEditField
        MaxFuncEvalEditField_5Label     matlab.ui.control.Label
        MaxFuncEvalEditField_5          matlab.ui.control.NumericEditField
        FuncTolEditField_5Label         matlab.ui.control.Label
        FuncTolEditField_5              matlab.ui.control.NumericEditField
        MaxIterEditField_5Label         matlab.ui.control.Label
        MaxIterEditField_5              matlab.ui.control.NumericEditField
        PenFuncWeightEditField_5Label   matlab.ui.control.Label
        PenFuncWeightEditField_5        matlab.ui.control.NumericEditField
        Stage3Panel_2                   matlab.ui.container.Panel
        swarmsizeEditField_6Label       matlab.ui.control.Label
        swarmsizeEditField_6            matlab.ui.control.NumericEditField
        MaxFuncEvalEditField_6Label     matlab.ui.control.Label
        MaxFuncEvalEditField_6          matlab.ui.control.NumericEditField
        FuncTolEditField_6Label         matlab.ui.control.Label
        FuncTolEditField_6              matlab.ui.control.NumericEditField
        MaxIterEditField_6Label         matlab.ui.control.Label
        MaxIterEditField_6              matlab.ui.control.NumericEditField
        PenFuncWeightEditField_6Label   matlab.ui.control.Label
        PenFuncWeightEditField_6        matlab.ui.control.NumericEditField
        CloseButton                     matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        STARTButton                     matlab.ui.control.Button
        ViewResultsButton               matlab.ui.control.Button
        OptimizationLabel_2             matlab.ui.control.Label
        OptimizationFileEditFieldLabel  matlab.ui.control.Label
        OptimizationFileEditField       matlab.ui.control.EditField
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                 load(['SavedResults/','gui_curr.mat']);
            
                load(['SavedResults/',gui_curr.opt_name]);
                
                h = eval(gui_curr.opt_name(1:end-4));
                
                app.OptimizationFileEditField.Value = gui_curr.opt_name;
                app.OptimizationFileEditField.Editable = 'on';
                app.Stage1DropDown.Value = h.stage1.type;
                app.Stage2DropDown.Value = h.stage2.type;
                app.Stage3DropDown.Value = h.stage3.type;
                
                Stage1DropDownValueChanged(app);
                Stage2DropDownValueChanged(app);
                Stage3DropDownValueChanged(app);
                
                app.percentageEditField.Value = h.KED.per;
                
                app.EditField_31.Value = h.lb_freq(1);
                app.EditField_33.Value = h.lb_freq(2);
                app.EditField_34.Value = h.lb_freq(3);
                app.EditField_35.Value = h.lb_freq(4);
                app.EditField_36.Value = h.lb_freq(5);
                app.EditField_37.Value = h.lb_freq(6);
                
                
                app.EditField_32.Value = h.ub_freq(1);
                app.EditField_38.Value = h.ub_freq(2);
                app.EditField_39.Value = h.ub_freq(3);
                app.EditField_40.Value = h.ub_freq(4);
                app.EditField_41.Value = h.ub_freq(5);
                app.EditField_42.Value = h.ub_freq(6);
                
                app.EditField_25.Value = h.DeltaStatic(1);
                app.EditField_26.Value = h.DeltaStatic(2);
                app.EditField_27.Value = h.DeltaStatic(3);
                app.EditField_28.Value = h.DeltaStatic(4);
                app.EditField_29.Value = h.DeltaStatic(5);
                app.EditField_30.Value = h.DeltaStatic(6);
                
                app.Stage1Panel_2.Visible = 'off';
                app.Stage2Panel_2.Visible = 'off';
                app.Stage3Panel_2.Visible = 'off';
                if strcmp(h.stage1.type,'None')==0
                    app.Stage1Panel_2.Visible = 'on';
                app.swarmsizeEditField_4.Value = h.stage1.swarmsize;
                app.MaxFuncEvalEditField_4.Value = h.stage1.MaxFuncEval;
                app.FuncTolEditField_4.Value = h.stage1.FuncTol;
                app.MaxIterEditField_4.Value = h.stage1.MaxIter;
                app.PenFuncWeightEditField_4.Value = h.stage1.PenFuncWeight;
                end
                if strcmp(h.stage2.type,'None')==0
                    app.Stage2Panel_2.Visible = 'on';
                app.swarmsizeEditField_5.Value = h.stage2.swarmsize;
                app.MaxFuncEvalEditField_5.Value = h.stage2.MaxFuncEval;
                app.FuncTolEditField_5.Value = h.stage2.FuncTol;
                app.MaxIterEditField_5.Value = h.stage2.MaxIter;
                app.PenFuncWeightEditField_5.Value = h.stage2.PenFuncWeight;
                end
                if strcmp(h.stage3.type,'None')==0
                    app.Stage3Panel_2.Visible = 'on';
                app.swarmsizeEditField_6.Value = h.stage3.swarmsize;
                app.MaxFuncEvalEditField_6.Value = h.stage3.MaxFuncEval;
                app.FuncTolEditField_6.Value = h.stage3.FuncTol;
                app.MaxIterEditField_6.Value = h.stage3.MaxIter;
                app.PenFuncWeightEditField_6.Value = h.stage3.PenFuncWeight;
                end


                %                 load('SavedResults/temp_optt.mat');
                
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
                load(['SavedResults/','gui_curr.mat']);
                load(['SavedResults/',gui_curr.opt_name]);
                load(['SavedResults/',gui_curr.input_name]);
                
                h = eval(gui_curr.opt_name(1:end-4));
                
                g = eval(gui_curr.input_name(1:end-4));
                h.sus = g.sus;
                h.eng = g.eng;
                h.mount = g.mount;
                
                h.stage1.type = app.Stage1DropDown.Value;
                h.stage2.type = app.Stage2DropDown.Value;
                h.stage3.type = app.Stage3DropDown.Value;
                h.lb = [h.mount.lb_r_1,h.mount.lb_r_2,h.mount.lb_r_3,...
                        h.mount.lb_o_1,h.mount.lb_o_2,h.mount.lb_o_3,...
                        h.mount.lb_k_1,h.mount.lb_k_2,h.mount.lb_k_3];
                h.ub = [h.mount.ub_r_1,h.mount.ub_r_2,h.mount.ub_r_3,...
                        h.mount.ub_o_1,h.mount.ub_o_2,h.mount.ub_o_3,...
                        h.mount.ub_k_1,h.mount.ub_k_2,h.mount.ub_k_3];
                h.KED.per = app.percentageEditField.Value;
                h.DeltaStatic =[app.EditField_25.Value,app.EditField_26.Value,...
                                app.EditField_27.Value,app.EditField_28.Value,...
                                app.EditField_29.Value,app.EditField_30.Value];
                h.ub_freq =[app.EditField_32.Value,app.EditField_38.Value,...
                            app.EditField_39.Value,app.EditField_40.Value,...
                            app.EditField_41.Value,app.EditField_42.Value];
                h.lb_freq =[app.EditField_31.Value,app.EditField_33.Value,...
                            app.EditField_34.Value,app.EditField_35.Value,...
                            app.EditField_36.Value,app.EditField_37.Value];
                h.stage1.swarmsize = app.swarmsizeEditField_4.Value;
                h.stage2.swarmsize = app.swarmsizeEditField_5.Value;
                h.stage3.swarmsize = app.swarmsizeEditField_6.Value;
                h.stage1.MaxFuncEval = app.MaxFuncEvalEditField_4.Value;
                h.stage2.MaxFuncEval = app.MaxFuncEvalEditField_5.Value;
                h.stage3.MaxFuncEval = app.MaxFuncEvalEditField_6.Value;
                h.stage1.FuncTol = app.FuncTolEditField_4.Value;
                h.stage2.FuncTol = app.FuncTolEditField_5.Value;
                h.stage3.FuncTol = app.FuncTolEditField_6.Value;
                h.stage1.MaxIter = app.MaxIterEditField_4.Value;
                h.stage2.MaxIter = app.MaxIterEditField_5.Value;
                h.stage3.MaxIter = app.MaxIterEditField_6.Value;
                h.stage1.PenFuncWeight = app.PenFuncWeightEditField_4.Value;
                h.stage2.PenFuncWeight = app.PenFuncWeightEditField_5.Value;
                h.stage3.PenFuncWeight = app.PenFuncWeightEditField_6.Value;
                
                load(['SavedResults/','gui_curr']);
                delete(['SavedResults/',gui_curr.opt_name]);
                gui_curr.opt_name = app.OptimizationFileEditField.Value;
                eval([gui_curr.opt_name(1:end-4),'=h;']);
                save(['SavedResults/',gui_curr.opt_name],gui_curr.opt_name(1:end-4));
                delete(app);
                
               
        end

        % Value changed function: Stage1DropDown
        function Stage1DropDownValueChanged(app, event)
            value = app.Stage1DropDown.Value;
            app.Stage1Panel_2.Visible = 'on';
            x=15; y=200; w=275; h=220;
            switch value
                case 'TRA'
                    app.ArPanel_1.Visible = 'off'
                    app.TFPanel_1.Visible = 'off'
                    app.TAPanel_1.Visible = 'off'
                    
                case 'TF'
                    app.ArPanel_1.Visible = 'off'
                    app.TFPanel_1.Visible = 'on'
                    app.TAPanel_1.Visible = 'off'
                    app.TFPanel_1.Position = [x,y,w,h];
                case 'TA'
                    app.ArPanel_1.Visible = 'off'
                    app.TFPanel_1.Visible = 'off'
                    app.TAPanel_1.Visible = 'on'
                    app.TAPanel_1.Position = [x,y,w,h];
                case 'Ar'
                    app.ArPanel_1.Visible = 'on'
                    app.TFPanel_1.Visible = 'off'
                    app.TAPanel_1.Visible = 'off'
                    app.ArPanel_1.Position = [x,y,w,h];
                case 'None'
                    app.Stage1Panel_2.Visible = 'off';
                    
            end
                    
        end

        % Callback function
        function Stage2CheckBoxValueChanged(app, event)
            value = app.Stage2CheckBox.Value;
            if value==1
                app.Stage2Panel.Visible = 'on';
            else 
                app.Stage2Panel.Visible = 'off';     
            end
        end

        % Value changed function: Stage2DropDown
        function Stage2DropDownValueChanged(app, event)
            value = app.Stage2DropDown.Value;
            app.Stage2Panel_2.Visible = 'on';
            
            x=300; y=200; w=275; h=220;
            
            switch value
                case 'TRA'
                    app.ArPanel_2.Visible = 'off'
                    app.TFPanel_2.Visible = 'off'
                    app.TAPanel_2.Visible = 'off'
                    
                case 'TF'
                    app.ArPanel_2.Visible = 'off'
                    app.TFPanel_2.Visible = 'on'
                    app.TAPanel_2.Visible = 'off'
                    app.TFPanel_2.Position = [x,y,w,h];
                case 'TA'
                    app.ArPanel_2.Visible = 'off'
                    app.TFPanel_2.Visible = 'off'
                    app.TAPanel_2.Visible = 'on'
                    app.TAPanel_2.Position = [x,y,w,h];
                case 'Ar'
                    app.ArPanel_2.Visible = 'on'
                    app.TFPanel_2.Visible = 'off'
                    app.TAPanel_2.Visible = 'off'
                    app.ArPanel_2.Position = [x,y,w,h];
                case 'None'   
                    app.ArPanel_2.Visible = 'off'
                    app.TFPanel_2.Visible = 'off'
                    app.TAPanel_2.Visible = 'off'
                    app.Stage2Panel_2.Visible = 'off';
                 
            end
            
        end

        % Callback function
        function Stage3CheckBoxValueChanged(app, event)
            value = app.Stage3CheckBox.Value;
            if value==1
                app.Stage3Panel.Visible = 'on';
            else 
                app.Stage3Panel.Visible = 'off';     
            end
        end

        % Value changed function: Stage3DropDown
        function Stage3DropDownValueChanged(app, event)
            value = app.Stage3DropDown.Value;
            app.Stage3Panel_2.Visible = 'on';
            
            x=600; y=200; w=275; h=220;
            switch value
                case 'TRA'
                    app.ArPanel_3.Visible = 'off'
                    app.TFPanel_3.Visible = 'off'
                    app.TAPanel_3.Visible = 'off'
                    
                case 'TF'
                    app.ArPanel_3.Visible = 'off'
                    app.TFPanel_3.Visible = 'on'
                    app.TAPanel_3.Visible = 'off'
                    app.TFPanel_3.Position = [x,y,w,h];
                case 'TA'
                    app.ArPanel_3.Visible = 'off'
                    app.TFPanel_3.Visible = 'off'
                    app.TAPanel_3.Visible = 'on'
                    app.TAPanel_3.Position = [x,y,w,h];
                case 'Ar'
                    app.ArPanel_3.Visible = 'on'
                    app.TFPanel_3.Visible = 'off'
                    app.TAPanel_3.Visible = 'off'
                    app.ArPanel_3.Position = [x,y,w,h];
                case 'None'   
                    app.ArPanel_3.Visible = 'off'
                    app.TFPanel_3.Visible = 'off'
                    app.TAPanel_3.Visible = 'off'
                    app.Stage3Panel_2.Visible = 'off';
            
            end
            
        end

        % Callback function
        function TRASettingsButtonPushed(app, event)
            gui_TRAoption
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OptimizationUIFigure = uifigure;
            app.OptimizationUIFigure.Name = 'Optimization';
            app.TabGroup = uitabgroup(app.OptimizationUIFigure);
            app.StrategyTab = uitab(app.TabGroup);
            app.StrategyTab.Title = 'Strategy';
            app.TFPanel_1 = uipanel(app.StrategyTab);
            app.TFPanel_1.Title = 'Transmitted Force';
            app.CriteriaButtonGroup_4 = uibuttongroup(app.TFPanel_1);
            app.CriteriaButtonGroup_4.Title = 'Criteria';
            app.MinimizemaximumForceButton_2 = uiradiobutton(app.CriteriaButtonGroup_4);
            app.MinimizemaximumForceButton_2.Text = 'Minimize maximum Force';
            app.MinimizemaximumForceButton_2.Value = true;
            app.MinimizesummationofForcesButton_2 = uiradiobutton(app.CriteriaButtonGroup_4);
            app.MinimizesummationofForcesButton_2.Text = 'Minimize summation of Forces';
            app.SelectedDirectionPanel_3 = uipanel(app.TFPanel_1);
            app.SelectedDirectionPanel_3.Title = 'Selected Direction';
            app.LocalXCheckBox_4 = uicheckbox(app.SelectedDirectionPanel_3);
            app.LocalXCheckBox_4.Text = 'Local X';
            app.LocalYCheckBox_4 = uicheckbox(app.SelectedDirectionPanel_3);
            app.LocalYCheckBox_4.Text = 'Local Y';
            app.LocalZCheckBox_4 = uicheckbox(app.SelectedDirectionPanel_3);
            app.LocalZCheckBox_4.Text = 'Local Z';
            app.MagnitudeCheckBox_4 = uicheckbox(app.SelectedDirectionPanel_3);
            app.MagnitudeCheckBox_4.Text = 'Magnitude';
            app.TAPanel_1 = uipanel(app.StrategyTab);
            app.TAPanel_1.Title = 'Transmitted Acceleration';
            app.CriteriaButtonGroup_5 = uibuttongroup(app.TAPanel_1);
            app.CriteriaButtonGroup_5.Title = 'Criteria';
            app.MinimizemaximumAccelerationButton_2 = uiradiobutton(app.CriteriaButtonGroup_5);
            app.MinimizemaximumAccelerationButton_2.Text = 'Minimize maximum Acceleration';
            app.MinimizemaximumAccelerationButton_2.Value = true;
            app.MinimizesummationofAccelerationsButton_2 = uiradiobutton(app.CriteriaButtonGroup_5);
            app.MinimizesummationofAccelerationsButton_2.Text = 'Minimize summation of Accelerations';
            app.SelectedDirectionPanel_4 = uipanel(app.TAPanel_1);
            app.SelectedDirectionPanel_4.Title = 'Selected Direction';
            app.LocalXCheckBox_5 = uicheckbox(app.SelectedDirectionPanel_4);
            app.LocalXCheckBox_5.Text = 'Local X';
            app.LocalYCheckBox_5 = uicheckbox(app.SelectedDirectionPanel_4);
            app.LocalYCheckBox_5.Text = 'Local Y';
            app.LocalZCheckBox_5 = uicheckbox(app.SelectedDirectionPanel_4);
            app.LocalZCheckBox_5.Text = 'Local Z';
            app.MagnitudeCheckBox_5 = uicheckbox(app.SelectedDirectionPanel_4);
            app.MagnitudeCheckBox_5.Text = 'Magnitude';
            app.ArPanel_1 = uipanel(app.StrategyTab);
            app.ArPanel_1.Title = 'Acceleration ratio';
            app.CriteriaButtonGroup_6 = uibuttongroup(app.ArPanel_1);
            app.CriteriaButtonGroup_6.Title = 'Criteria';
            app.MinimizemaximumAccelerationratioButton_2 = uiradiobutton(app.CriteriaButtonGroup_6);
            app.MinimizemaximumAccelerationratioButton_2.Text = 'Minimize maximum Acceleration ratio';
            app.MinimizemaximumAccelerationratioButton_2.Value = true;
            app.MinimizesummationofAccelerationratiosButton_2 = uiradiobutton(app.CriteriaButtonGroup_6);
            app.MinimizesummationofAccelerationratiosButton_2.Text = 'Minimize summation of Acceleration ratios';
            app.SelectedDirectionsPanel_2 = uipanel(app.ArPanel_1);
            app.SelectedDirectionsPanel_2.Title = 'Selected Directions';
            app.LocalXCheckBox_6 = uicheckbox(app.SelectedDirectionsPanel_2);
            app.LocalXCheckBox_6.Text = 'Local X';
            app.LocalYCheckBox_6 = uicheckbox(app.SelectedDirectionsPanel_2);
            app.LocalYCheckBox_6.Text = 'Local Y';
            app.LocalZCheckBox_6 = uicheckbox(app.SelectedDirectionsPanel_2);
            app.LocalZCheckBox_6.Text = 'Local Z';
            app.MagnitudeCheckBox_6 = uicheckbox(app.SelectedDirectionsPanel_2);
            app.MagnitudeCheckBox_6.Text = 'Magnitude';
            app.Stage1DropDownLabel = uilabel(app.StrategyTab);
            app.Stage1DropDownLabel.Text = 'Stage 1';
            app.Stage1DropDown = uidropdown(app.StrategyTab);
            app.Stage1DropDown.Items = {'TRA', 'TF', 'TA', 'Ar'};
            app.Stage1DropDown.ValueChangedFcn = createCallbackFcn(app, @Stage1DropDownValueChanged, true);
            app.Stage1DropDown.Value = 'TRA';
            app.Stage2DropDownLabel = uilabel(app.StrategyTab);
            app.Stage2DropDownLabel.Text = 'Stage 2';
            app.Stage2DropDown = uidropdown(app.StrategyTab);
            app.Stage2DropDown.Items = {'TRA', 'None', 'TF', 'TA', 'Ar'};
            app.Stage2DropDown.ValueChangedFcn = createCallbackFcn(app, @Stage2DropDownValueChanged, true);
            app.Stage2DropDown.Value = 'None';
            app.Stage3DropDownLabel = uilabel(app.StrategyTab);
            app.Stage3DropDownLabel.Text = 'Stage 3';
            app.Stage3DropDown = uidropdown(app.StrategyTab);
            app.Stage3DropDown.Items = {'TRA', 'None', 'TF', 'TA', 'Ar'};
            app.Stage3DropDown.ValueChangedFcn = createCallbackFcn(app, @Stage3DropDownValueChanged, true);
            app.Stage3DropDown.Value = 'None';
            app.TAPanel_2 = uipanel(app.StrategyTab);
            app.TAPanel_2.Title = 'Transmitted Acceleration';
            app.CriteriaButtonGroup_7 = uibuttongroup(app.TAPanel_2);
            app.CriteriaButtonGroup_7.Title = 'Criteria';
            app.MinimizemaximumAccelerationButton_3 = uiradiobutton(app.CriteriaButtonGroup_7);
            app.MinimizemaximumAccelerationButton_3.Text = 'Minimize maximum Acceleration';
            app.MinimizemaximumAccelerationButton_3.Value = true;
            app.MinimizesummationofAccelerationsButton_3 = uiradiobutton(app.CriteriaButtonGroup_7);
            app.MinimizesummationofAccelerationsButton_3.Text = 'Minimize summation of Accelerations';
            app.SelectedDirectionPanel_5 = uipanel(app.TAPanel_2);
            app.SelectedDirectionPanel_5.Title = 'Selected Direction';
            app.LocalXCheckBox_7 = uicheckbox(app.SelectedDirectionPanel_5);
            app.LocalXCheckBox_7.Text = 'Local X';
            app.LocalYCheckBox_7 = uicheckbox(app.SelectedDirectionPanel_5);
            app.LocalYCheckBox_7.Text = 'Local Y';
            app.LocalZCheckBox_7 = uicheckbox(app.SelectedDirectionPanel_5);
            app.LocalZCheckBox_7.Text = 'Local Z';
            app.MagnitudeCheckBox_7 = uicheckbox(app.SelectedDirectionPanel_5);
            app.MagnitudeCheckBox_7.Text = 'Magnitude';
            app.TAPanel_3 = uipanel(app.StrategyTab);
            app.TAPanel_3.Title = 'Transmitted Acceleration';
            app.CriteriaButtonGroup_8 = uibuttongroup(app.TAPanel_3);
            app.CriteriaButtonGroup_8.Title = 'Criteria';
            app.MinimizemaximumAccelerationButton_4 = uiradiobutton(app.CriteriaButtonGroup_8);
            app.MinimizemaximumAccelerationButton_4.Text = 'Minimize maximum Acceleration';
            app.MinimizemaximumAccelerationButton_4.Value = true;
            app.MinimizesummationofAccelerationsButton_4 = uiradiobutton(app.CriteriaButtonGroup_8);
            app.MinimizesummationofAccelerationsButton_4.Text = 'Minimize summation of Accelerations';
            app.SelectedDirectionPanel_6 = uipanel(app.TAPanel_3);
            app.SelectedDirectionPanel_6.Title = 'Selected Direction';
            app.LocalXCheckBox_8 = uicheckbox(app.SelectedDirectionPanel_6);
            app.LocalXCheckBox_8.Text = 'Local X';
            app.LocalYCheckBox_8 = uicheckbox(app.SelectedDirectionPanel_6);
            app.LocalYCheckBox_8.Text = 'Local Y';
            app.LocalZCheckBox_8 = uicheckbox(app.SelectedDirectionPanel_6);
            app.LocalZCheckBox_8.Text = 'Local Z';
            app.MagnitudeCheckBox_8 = uicheckbox(app.SelectedDirectionPanel_6);
            app.MagnitudeCheckBox_8.Text = 'Magnitude';
            app.TFPanel_2 = uipanel(app.StrategyTab);
            app.TFPanel_2.Title = 'Transmitted Force';
            app.CriteriaButtonGroup_9 = uibuttongroup(app.TFPanel_2);
            app.CriteriaButtonGroup_9.Title = 'Criteria';
            app.MinimizemaximumForceButton_3 = uiradiobutton(app.CriteriaButtonGroup_9);
            app.MinimizemaximumForceButton_3.Text = 'Minimize maximum Force';
            app.MinimizemaximumForceButton_3.Value = true;
            app.MinimizesummationofForcesButton_3 = uiradiobutton(app.CriteriaButtonGroup_9);
            app.MinimizesummationofForcesButton_3.Text = 'Minimize summation of Forces';
            app.SelectedDirectionPanel_7 = uipanel(app.TFPanel_2);
            app.SelectedDirectionPanel_7.Title = 'Selected Direction';
            app.LocalXCheckBox_9 = uicheckbox(app.SelectedDirectionPanel_7);
            app.LocalXCheckBox_9.Text = 'Local X';
            app.LocalYCheckBox_9 = uicheckbox(app.SelectedDirectionPanel_7);
            app.LocalYCheckBox_9.Text = 'Local Y';
            app.LocalZCheckBox_9 = uicheckbox(app.SelectedDirectionPanel_7);
            app.LocalZCheckBox_9.Text = 'Local Z';
            app.MagnitudeCheckBox_9 = uicheckbox(app.SelectedDirectionPanel_7);
            app.MagnitudeCheckBox_9.Text = 'Magnitude';
            app.TFPanel_3 = uipanel(app.StrategyTab);
            app.TFPanel_3.Title = 'Transmitted Force';
            app.CriteriaButtonGroup_10 = uibuttongroup(app.TFPanel_3);
            app.CriteriaButtonGroup_10.Title = 'Criteria';
            app.MinimizemaximumForceButton_4 = uiradiobutton(app.CriteriaButtonGroup_10);
            app.MinimizemaximumForceButton_4.Text = 'Minimize maximum Force';
            app.MinimizemaximumForceButton_4.Value = true;
            app.MinimizesummationofForcesButton_4 = uiradiobutton(app.CriteriaButtonGroup_10);
            app.MinimizesummationofForcesButton_4.Text = 'Minimize summation of Forces';
            app.SelectedDirectionPanel_8 = uipanel(app.TFPanel_3);
            app.SelectedDirectionPanel_8.Title = 'Selected Direction';
            app.LocalXCheckBox_10 = uicheckbox(app.SelectedDirectionPanel_8);
            app.LocalXCheckBox_10.Text = 'Local X';
            app.LocalYCheckBox_10 = uicheckbox(app.SelectedDirectionPanel_8);
            app.LocalYCheckBox_10.Text = 'Local Y';
            app.LocalZCheckBox_10 = uicheckbox(app.SelectedDirectionPanel_8);
            app.LocalZCheckBox_10.Text = 'Local Z';
            app.MagnitudeCheckBox_10 = uicheckbox(app.SelectedDirectionPanel_8);
            app.MagnitudeCheckBox_10.Text = 'Magnitude';
            app.ArPanel_2 = uipanel(app.StrategyTab);
            app.ArPanel_2.Title = 'Acceleration ratio';
            app.CriteriaButtonGroup_11 = uibuttongroup(app.ArPanel_2);
            app.CriteriaButtonGroup_11.Title = 'Criteria';
            app.MinimizemaximumAccelerationratioButton_3 = uiradiobutton(app.CriteriaButtonGroup_11);
            app.MinimizemaximumAccelerationratioButton_3.Text = 'Minimize maximum Acceleration ratio';
            app.MinimizemaximumAccelerationratioButton_3.Value = true;
            app.MinimizesummationofAccelerationratiosButton_3 = uiradiobutton(app.CriteriaButtonGroup_11);
            app.MinimizesummationofAccelerationratiosButton_3.Text = 'Minimize summation of Acceleration ratios';
            app.SelectedDirectionsPanel_3 = uipanel(app.ArPanel_2);
            app.SelectedDirectionsPanel_3.Title = 'Selected Directions';
            app.LocalXCheckBox_11 = uicheckbox(app.SelectedDirectionsPanel_3);
            app.LocalXCheckBox_11.Text = 'Local X';
            app.LocalYCheckBox_11 = uicheckbox(app.SelectedDirectionsPanel_3);
            app.LocalYCheckBox_11.Text = 'Local Y';
            app.LocalZCheckBox_11 = uicheckbox(app.SelectedDirectionsPanel_3);
            app.LocalZCheckBox_11.Text = 'Local Z';
            app.MagnitudeCheckBox_11 = uicheckbox(app.SelectedDirectionsPanel_3);
            app.MagnitudeCheckBox_11.Text = 'Magnitude';
            app.ArPanel_3 = uipanel(app.StrategyTab);
            app.ArPanel_3.Title = 'Acceleration ratio';
            app.CriteriaButtonGroup_12 = uibuttongroup(app.ArPanel_3);
            app.CriteriaButtonGroup_12.Title = 'Criteria';
            app.MinimizemaximumAccelerationratioButton_4 = uiradiobutton(app.CriteriaButtonGroup_12);
            app.MinimizemaximumAccelerationratioButton_4.Text = 'Minimize maximum Acceleration ratio';
            app.MinimizemaximumAccelerationratioButton_4.Value = true;
            app.MinimizesummationofAccelerationratiosButton_4 = uiradiobutton(app.CriteriaButtonGroup_12);
            app.MinimizesummationofAccelerationratiosButton_4.Text = 'Minimize summation of Acceleration ratios';
            app.SelectedDirectionsPanel_4 = uipanel(app.ArPanel_3);
            app.SelectedDirectionsPanel_4.Title = 'Selected Directions';
            app.LocalXCheckBox_12 = uicheckbox(app.SelectedDirectionsPanel_4);
            app.LocalXCheckBox_12.Text = 'Local X';
            app.LocalYCheckBox_12 = uicheckbox(app.SelectedDirectionsPanel_4);
            app.LocalYCheckBox_12.Text = 'Local Y';
            app.LocalZCheckBox_12 = uicheckbox(app.SelectedDirectionsPanel_4);
            app.LocalZCheckBox_12.Text = 'Local Z';
            app.MagnitudeCheckBox_12 = uicheckbox(app.SelectedDirectionsPanel_4);
            app.MagnitudeCheckBox_12.Text = 'Magnitude';
            app.ConstraintsTab_2 = uitab(app.TabGroup);
            app.ConstraintsTab_2.Title = 'Constraints';
            app.KEDcriteriaCheckBox = uicheckbox(app.ConstraintsTab_2);
            app.KEDcriteriaCheckBox.Text = 'KED criteria';
            app.KEDcriteriaCheckBox.Value = true;
            app.percentageEditFieldLabel = uilabel(app.ConstraintsTab_2);
            app.percentageEditFieldLabel.Text = 'percentage';
            app.percentageEditField = uieditfield(app.ConstraintsTab_2, 'numeric');
            app.percentageEditField.Value = 85;
            app.MountloactionorientationButton = uibutton(app.ConstraintsTab_2, 'push');
            app.MountloactionorientationButton.Text = 'Mount loaction & orientation';
            app.NaturalFrequenciesPanel = uipanel(app.ConstraintsTab_2);
            app.NaturalFrequenciesPanel.Title = 'Natural Frequencies';
            app.LongitudinalLabel_3 = uilabel(app.NaturalFrequenciesPanel);
            app.LongitudinalLabel_3.Text = 'Longitudinal';
            app.LateralLabel_4 = uilabel(app.NaturalFrequenciesPanel);
            app.LateralLabel_4.Text = 'Lateral';
            app.BounceLabel_4 = uilabel(app.NaturalFrequenciesPanel);
            app.BounceLabel_4.Text = 'Bounce';
            app.RollLabel_4 = uilabel(app.NaturalFrequenciesPanel);
            app.RollLabel_4.Text = 'Roll';
            app.PitchLabel_4 = uilabel(app.NaturalFrequenciesPanel);
            app.PitchLabel_4.Text = 'Pitch';
            app.BounceLabel_5 = uilabel(app.NaturalFrequenciesPanel);
            app.BounceLabel_5.Text = 'Bounce';
            app.EditField_31 = uieditfield(app.NaturalFrequenciesPanel, 'numeric');
            app.LowerHzEditFieldLabel_2 = uilabel(app.NaturalFrequenciesPanel);
            app.LowerHzEditFieldLabel_2.Text = 'Lower (Hz)';
            app.EditField_32 = uieditfield(app.NaturalFrequenciesPanel, 'numeric');
            app.UpperHzEditFieldLabel_2 = uilabel(app.NaturalFrequenciesPanel);
            app.UpperHzEditFieldLabel_2.Text = 'Upper (Hz)';
            app.EditField_33 = uieditfield(app.NaturalFrequenciesPanel, 'numeric');
            app.EditField_34 = uieditfield(app.NaturalFrequenciesPanel, 'numeric');
            app.EditField_35 = uieditfield(app.NaturalFrequenciesPanel, 'numeric');
            app.EditField_36 = uieditfield(app.NaturalFrequenciesPanel, 'numeric');
            app.EditField_37 = uieditfield(app.NaturalFrequenciesPanel, 'numeric');
            app.EditField_38 = uieditfield(app.NaturalFrequenciesPanel, 'numeric');
            app.EditField_39 = uieditfield(app.NaturalFrequenciesPanel, 'numeric');
            app.EditField_40 = uieditfield(app.NaturalFrequenciesPanel, 'numeric');
            app.EditField_41 = uieditfield(app.NaturalFrequenciesPanel, 'numeric');
            app.EditField_42 = uieditfield(app.NaturalFrequenciesPanel, 'numeric');
            app.StaticDeflectionsPanel = uipanel(app.ConstraintsTab_2);
            app.StaticDeflectionsPanel.Title = 'Static ِDeflections';
            app.LongitudinalLabel_2 = uilabel(app.StaticDeflectionsPanel);
            app.LongitudinalLabel_2.Text = 'Longitudinal';
            app.mmLabel_3 = uilabel(app.StaticDeflectionsPanel);
            app.mmLabel_3.Text = 'mm';
            app.LateralLabel_3 = uilabel(app.StaticDeflectionsPanel);
            app.LateralLabel_3.Text = 'Lateral';
            app.mmLabel_4 = uilabel(app.StaticDeflectionsPanel);
            app.mmLabel_4.Text = 'mm';
            app.BounceLabel_3 = uilabel(app.StaticDeflectionsPanel);
            app.BounceLabel_3.Text = 'Bounce';
            app.mmLabel_5 = uilabel(app.StaticDeflectionsPanel);
            app.mmLabel_5.Text = 'mm';
            app.RollLabel_3 = uilabel(app.StaticDeflectionsPanel);
            app.RollLabel_3.Text = 'Roll';
            app.degLabel_4 = uilabel(app.StaticDeflectionsPanel);
            app.degLabel_4.Text = 'deg';
            app.PitchLabel_3 = uilabel(app.StaticDeflectionsPanel);
            app.PitchLabel_3.Text = 'Pitch';
            app.degLabel_5 = uilabel(app.StaticDeflectionsPanel);
            app.degLabel_5.Text = 'deg';
            app.YawLabel_3 = uilabel(app.StaticDeflectionsPanel);
            app.YawLabel_3.Text = 'Yaw';
            app.degLabel_6 = uilabel(app.StaticDeflectionsPanel);
            app.degLabel_6.Text = 'deg';
            app.EditField_25 = uieditfield(app.StaticDeflectionsPanel, 'numeric');
            app.EditField_26 = uieditfield(app.StaticDeflectionsPanel, 'numeric');
            app.EditField_27 = uieditfield(app.StaticDeflectionsPanel, 'numeric');
            app.EditField_28 = uieditfield(app.StaticDeflectionsPanel, 'numeric');
            app.EditField_29 = uieditfield(app.StaticDeflectionsPanel, 'numeric');
            app.EditField_30 = uieditfield(app.StaticDeflectionsPanel, 'numeric');
            app.OptionsTab = uitab(app.TabGroup);
            app.OptionsTab.Title = 'Options';
            app.Stage1Panel_2 = uipanel(app.OptionsTab);
            app.Stage1Panel_2.Title = 'Stage 1';
            app.swarmsizeEditField_4Label = uilabel(app.Stage1Panel_2);
            app.swarmsizeEditField_4Label.Text = 'swarmsize';
            app.swarmsizeEditField_4 = uieditfield(app.Stage1Panel_2, 'numeric');
            app.MaxFuncEvalEditField_4Label = uilabel(app.Stage1Panel_2);
            app.MaxFuncEvalEditField_4Label.Text = 'MaxFuncEval';
            app.MaxFuncEvalEditField_4 = uieditfield(app.Stage1Panel_2, 'numeric');
            app.FuncTolEditField_4Label = uilabel(app.Stage1Panel_2);
            app.FuncTolEditField_4Label.Text = 'FuncTol';
            app.FuncTolEditField_4 = uieditfield(app.Stage1Panel_2, 'numeric');
            app.MaxIterEditField_4Label = uilabel(app.Stage1Panel_2);
            app.MaxIterEditField_4Label.Text = 'MaxIter';
            app.MaxIterEditField_4 = uieditfield(app.Stage1Panel_2, 'numeric');
            app.PenFuncWeightEditField_4Label = uilabel(app.Stage1Panel_2);
            app.PenFuncWeightEditField_4Label.Text = 'PenFuncWeight';
            app.PenFuncWeightEditField_4 = uieditfield(app.Stage1Panel_2, 'numeric');
            app.Stage2Panel_2 = uipanel(app.OptionsTab);
            app.Stage2Panel_2.Title = 'Stage 2';
            app.swarmsizeEditField_5Label = uilabel(app.Stage2Panel_2);
            app.swarmsizeEditField_5Label.Text = 'swarmsize';
            app.swarmsizeEditField_5 = uieditfield(app.Stage2Panel_2, 'numeric');
            app.MaxFuncEvalEditField_5Label = uilabel(app.Stage2Panel_2);
            app.MaxFuncEvalEditField_5Label.Text = 'MaxFuncEval';
            app.MaxFuncEvalEditField_5 = uieditfield(app.Stage2Panel_2, 'numeric');
            app.FuncTolEditField_5Label = uilabel(app.Stage2Panel_2);
            app.FuncTolEditField_5Label.Text = 'FuncTol';
            app.FuncTolEditField_5 = uieditfield(app.Stage2Panel_2, 'numeric');
            app.MaxIterEditField_5Label = uilabel(app.Stage2Panel_2);
            app.MaxIterEditField_5Label.Text = 'MaxIter';
            app.MaxIterEditField_5 = uieditfield(app.Stage2Panel_2, 'numeric');
            app.PenFuncWeightEditField_5Label = uilabel(app.Stage2Panel_2);
            app.PenFuncWeightEditField_5Label.Text = 'PenFuncWeight';
            app.PenFuncWeightEditField_5 = uieditfield(app.Stage2Panel_2, 'numeric');
            app.Stage3Panel_2 = uipanel(app.OptionsTab);
            app.Stage3Panel_2.Title = 'Stage 3';
            app.swarmsizeEditField_6Label = uilabel(app.Stage3Panel_2);
            app.swarmsizeEditField_6Label.Text = 'swarmsize';
            app.swarmsizeEditField_6 = uieditfield(app.Stage3Panel_2, 'numeric');
            app.MaxFuncEvalEditField_6Label = uilabel(app.Stage3Panel_2);
            app.MaxFuncEvalEditField_6Label.Text = 'MaxFuncEval';
            app.MaxFuncEvalEditField_6 = uieditfield(app.Stage3Panel_2, 'numeric');
            app.FuncTolEditField_6Label = uilabel(app.Stage3Panel_2);
            app.FuncTolEditField_6Label.Text = 'FuncTol';
            app.FuncTolEditField_6 = uieditfield(app.Stage3Panel_2, 'numeric');
            app.MaxIterEditField_6Label = uilabel(app.Stage3Panel_2);
            app.MaxIterEditField_6Label.Text = 'MaxIter';
            app.MaxIterEditField_6 = uieditfield(app.Stage3Panel_2, 'numeric');
            app.PenFuncWeightEditField_6Label = uilabel(app.Stage3Panel_2);
            app.PenFuncWeightEditField_6Label.Text = 'PenFuncWeight';
            app.PenFuncWeightEditField_6 = uieditfield(app.Stage3Panel_2, 'numeric');
            app.CloseButton = uibutton(app.OptimizationUIFigure, 'push');
            app.CloseButton.Text = 'Close';
            app.SaveButton = uibutton(app.OptimizationUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.STARTButton = uibutton(app.OptimizationUIFigure, 'push');
            app.STARTButton.Text = 'START';
            app.ViewResultsButton = uibutton(app.OptimizationUIFigure, 'push');
            app.ViewResultsButton.Text = 'View Results';
            app.OptimizationLabel_2 = uilabel(app.OptimizationUIFigure);
            app.OptimizationLabel_2.Text = 'Optimization';
            app.OptimizationFileEditFieldLabel = uilabel(app.OptimizationUIFigure);
            app.OptimizationFileEditFieldLabel.Text = 'Optimization File :';
            app.OptimizationFileEditField = uieditfield(app.OptimizationUIFigure, 'text');
        end
    end

    methods (Access = public)

        % Construct app
        function app = gui_optimization

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OptimizationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OptimizationUIFigure)
        end
    end
end